classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        TabGroup   matlab.ui.container.TabGroup
        Tab        matlab.ui.container.Tab
        Image      matlab.ui.control.Image
        Button_21  matlab.ui.control.Button
        Button_22  matlab.ui.control.Button
        Label      matlab.ui.control.Label
    end

    
    properties (Access = private)
        player1UI % Description
        player2UI
        player3UI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            close all
            clear all
            game = Game;
            
            process = Processor;
            app.player1UI = player1;
            app.player1UI.InfoBox.Title = "玩家1 信息";
            app.player1UI.player2Label.Text = '玩家2剩余牌数';
            app.player1UI.player3Label.Text = '玩家3剩余牌数';
            app.player2UI = player1;
            app.player2UI.InfoBox.Title = "玩家2 信息";
            app.player2UI.player2Label.Text = '玩家3剩余牌数';
            app.player2UI.player3Label.Text = '玩家1剩余牌数';
            app.player3UI = player1;    
            app.player3UI.InfoBox.Title = "玩家3 信息";
            app.player3UI.player2Label.Text = '玩家1剩余牌数';
            app.player3UI.player3Label.Text = '玩家2剩余牌数';
            app.player1UI.Game = game;
            app.player2UI.Game = game;
            app.player3UI.Game = game;
            
            process.game = Game;
            game.Process = process;
            process.player1App = app.player1UI;
            process.player2App = app.player2UI;
            process.player3App = app.player3UI;
            game.startgame(process);
            game.playgame_firstroundstart();
            game.playgame_roundstart(game.Process);

        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
%             app.player1UI.callUIFigureCloseRequest();
%             app.player2UI.callUIFigureCloseRequest();
%             app.player3UI.callUIFigureCloseRequest();
            delete(app.player1UI);
            delete(app.player2UI);
            
            delete(app.player3UI);
            
            clear app.player1UI;
            clear app.player2UI;
            clear app.player3UI;
            clear game;
            clear process;
%             app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'main.jpg';
            app.Button_21 = uibutton(app.Tab, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '开始游戏';
            app.Button_22 = uibutton(app.Tab, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '退出游戏';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '欢迎来到斗地主';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end