classdef version2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        player1Tab      matlab.ui.container.Tab
        STARTButton     matlab.ui.control.Button
        Button_1        matlab.ui.control.StateButton
        Button_2        matlab.ui.control.StateButton
        Button_3        matlab.ui.control.StateButton
        Button_4        matlab.ui.control.StateButton
        Button_5        matlab.ui.control.StateButton
        Button_6        matlab.ui.control.StateButton
        Button_7        matlab.ui.control.StateButton
        Button_8        matlab.ui.control.StateButton
        Button_9        matlab.ui.control.StateButton
        Button_10       matlab.ui.control.StateButton
        Button_11       matlab.ui.control.StateButton
        Button_12       matlab.ui.control.StateButton
        Button_13       matlab.ui.control.StateButton
        Button_14       matlab.ui.control.StateButton
        Button_15       matlab.ui.control.StateButton
        Button_16       matlab.ui.control.StateButton
        Button_17       matlab.ui.control.StateButton
        Button_18       matlab.ui.control.StateButton
        Button_19       matlab.ui.control.StateButton
        Button_20       matlab.ui.control.StateButton
        play_1          matlab.ui.control.Button
        cancel_1        matlab.ui.control.Button
        tip_1           matlab.ui.control.Button
        pass_1          matlab.ui.control.Button
        player1card_1   matlab.ui.control.TextArea
        player2card_1   matlab.ui.control.TextArea
        player3card_1   matlab.ui.control.TextArea
        multiplier_1    matlab.ui.control.TextArea
        call_1          matlab.ui.control.Button
        NOcall_1        matlab.ui.control.Button
        setting         matlab.ui.control.CheckBox
        player1info_1   matlab.ui.control.TextArea
        player2info_1   matlab.ui.control.TextArea
        player3info_1   matlab.ui.control.TextArea
        usr1            matlab.ui.control.TextArea
        usr2            matlab.ui.control.TextArea
        usr3            matlab.ui.control.TextArea
        usr_tag         matlab.ui.control.Label
        leave_1         matlab.ui.control.StateButton
        recorder_1      matlab.ui.control.Table
        setting_2       matlab.ui.control.CheckBox
        player2Tab      matlab.ui.container.Tab
        Button_21       matlab.ui.control.StateButton
        Button_22       matlab.ui.control.StateButton
        Button_23       matlab.ui.control.StateButton
        Button_24       matlab.ui.control.StateButton
        Button_25       matlab.ui.control.StateButton
        Button_26       matlab.ui.control.StateButton
        Button_27       matlab.ui.control.StateButton
        Button_28       matlab.ui.control.StateButton
        Button_29       matlab.ui.control.StateButton
        Button_30       matlab.ui.control.StateButton
        Button_31       matlab.ui.control.StateButton
        Button_32       matlab.ui.control.StateButton
        Button_33       matlab.ui.control.StateButton
        Button_34       matlab.ui.control.StateButton
        Button_35       matlab.ui.control.StateButton
        Button_36       matlab.ui.control.StateButton
        Button_37       matlab.ui.control.StateButton
        Button_38       matlab.ui.control.StateButton
        Button_39       matlab.ui.control.StateButton
        Button_40       matlab.ui.control.StateButton
        play_2          matlab.ui.control.Button
        cancel_2        matlab.ui.control.Button
        tip_2           matlab.ui.control.Button
        pass_2          matlab.ui.control.Button
        player2card_2   matlab.ui.control.TextArea
        player3card_2   matlab.ui.control.TextArea
        player1card_2   matlab.ui.control.TextArea
        multiplier_2    matlab.ui.control.TextArea
        call_2          matlab.ui.control.Button
        NOcall_2        matlab.ui.control.Button
        player2info_2   matlab.ui.control.TextArea
        player3info_2   matlab.ui.control.TextArea
        player1info_2   matlab.ui.control.TextArea
        leave_2         matlab.ui.control.StateButton
        recorder_2      matlab.ui.control.Table
        player3Tab      matlab.ui.container.Tab
        Button_41       matlab.ui.control.StateButton
        Button_42       matlab.ui.control.StateButton
        Button_43       matlab.ui.control.StateButton
        Button_44       matlab.ui.control.StateButton
        Button_45       matlab.ui.control.StateButton
        Button_46       matlab.ui.control.StateButton
        Button_47       matlab.ui.control.StateButton
        Button_48       matlab.ui.control.StateButton
        Button_49       matlab.ui.control.StateButton
        Button_50       matlab.ui.control.StateButton
        Button_51       matlab.ui.control.StateButton
        Button_52       matlab.ui.control.StateButton
        Button_53       matlab.ui.control.StateButton
        Button_54       matlab.ui.control.StateButton
        Button_55       matlab.ui.control.StateButton
        Button_56       matlab.ui.control.StateButton
        Button_57       matlab.ui.control.StateButton
        Button_58       matlab.ui.control.StateButton
        Button_59       matlab.ui.control.StateButton
        Button_60       matlab.ui.control.StateButton
        play_3          matlab.ui.control.Button
        cancel_3        matlab.ui.control.Button
        tip_3           matlab.ui.control.Button
        pass_3          matlab.ui.control.Button
        player3card_3   matlab.ui.control.TextArea
        player1card_3   matlab.ui.control.TextArea
        player2card_3   matlab.ui.control.TextArea
        multiplier_3    matlab.ui.control.TextArea
        call_3          matlab.ui.control.Button
        NOcall_3        matlab.ui.control.Button
        player3info_3   matlab.ui.control.TextArea
        player1info_3   matlab.ui.control.TextArea
        player2info_3   matlab.ui.control.TextArea
        leave_3         matlab.ui.control.StateButton
        recorder_3      matlab.ui.control.Table
        TransplayerTab  matlab.ui.container.Tab
        NxtPlayer       matlab.ui.control.Button
        UITable         matlab.ui.control.Table
        end_game        matlab.ui.control.Button
        end_game2       matlab.ui.control.Button
    end

    
    properties (Access = public)
        suit = ["♠","♥","♣","♦"];
        point = ["3","4","5","6","7","8","9","10","J","Q","K","A","2"];
        cards = "";
        shuffle;
        hand1;
        hand2;
        hand3;
        landlordID = 0;
        mutiplier = 1;
        wait_index;
        user_names = {};
        selected = [];  %  当前操作玩家选中的牌中，每一张的编号
        last_played = [0,0,0,0];  % 上一个出牌的玩家，出的牌型序号，点数大小，张数
        %------------------------
        state = 0;
        cur_player;
        clear;
        calling;
        call_info;
        call_button = ["call_","NOcall_"];
        operate_button = ["play_","cancel_","tip_","pass_"];  % 增加提示按键 "tip"
        info_button = ["multiplier_","player1info_","player2info_","player3info_","player1card_","player2card_","player3card_"];
        usr_table;
        usr_name;
        usr_id;
        play_info;
        winner;
    end

    methods (Access = public)
        
        function call_for(app,playerid)
            app.NxtPlayer.Text = "轮到玩家 " + playerid + " 操作";
            app.TabGroup.SelectedTab = app.TransplayerTab;
        end
        
        function result = card_get_point(app,id)  %  传入的id可能是数组
            if id > 52
                result = id - 39;
            else
                result = ceil(id/4);
            end
        end
        
        function validate_cards(app,buttonid,valid) % enable/unable app.Button_id
            eval("app.Button_" + num2str(buttonid) + ".Visible=" + num2str(valid)+";");
            eval("app.Button_" + num2str(buttonid) + ".Enable=" + num2str(valid)+";");
        end
        
        function write_cards(app,buttonid,sym)
            tmp = char(sym);
            if sym == "JOKER"
                sym = """J"",""O"",""K"",""E"",""R""";
            elseif sym == "joker"
                sym = """j"",""o"",""k"",""e"",""r""";
            elseif length(tmp) == 3
                sym = """" + string(tmp(1:2)) + """,""" + string(tmp(3)) + """";
            else
                sym = """" + string(tmp(1)) + """,""" + string(tmp(2)) + """";
            end
            if tmp == "JOKER" || tmp(length(tmp)) == "♥" || tmp(length(tmp)) == "♦"
                eval("app.Button_" + num2str(buttonid) + ".FontColor=[1,0,0];");
            else
                eval("app.Button_" + num2str(buttonid) + ".FontColor=[0,0,0];");
            end
            eval("app.Button_" + num2str(buttonid) + ".Text=[" + sym + "];");
        end
        
        function update_handcard(app,playerid)  %  更新玩家PLAYERID的手牌
            inhand = eval("app.hand" + num2str(playerid));
            count = length(inhand);
            for i = 1:count
                app.validate_cards((playerid-1)*20+i,1);
                app.write_cards((playerid-1)*20+i,app.cards(inhand(i)));
                eval("app.Button_"+num2str((playerid-1)*20+i)+".Value=0;");
            end
            for i = count+1:20
                app.validate_cards((playerid-1)*20+i,0);
            end
            for i = 1:3
                eval("app.player"+num2str(playerid)+"info_"+num2str(i)+".Value(3)={char(num2str(count))};");
            end
        end
        
        function results = auto_chose(app,playerid)  %  "提示"按键，帮助玩家自动选择手牌
            results = [];
            chose = "pass";  %  玩家选中的手牌的点数
            pt = app.last_played(3);
            len = app.last_played(4);
            handcard = eval("app.hand" + num2str(playerid));
            points = app.card_get_point(handcard);  %  手牌的点数，倒序排列
            if handcard(1) == 54  %  此处修复大王判断错误的重大bug：MATLAB布尔数组x全为1时，if x 才会执行
                points(1) = 15;
            end
            bucket = zeros(1,15);      %  手牌中，每种点数的张数
            for i = points
                bucket(i) = bucket(i) + 1;
            end
            if app.last_played(1) == playerid  %  托管状态下，该玩家发牌，则自动出最小的牌
                count = min(bucket(points(end))-1, 2);
                results = length(handcard)-count:length(handcard);
                return;
            end
            own1 = find(bucket >= 1);  %  手牌中含有至少一张的点数，正序排列
            own2 = find(bucket >= 2);
            own3 = find(bucket >= 3);
            own4 = find(bucket >= 4);
            switch app.last_played(2)  %  12中基本牌型，在手牌中寻找可用的牌，暂时不找炸弹
                case 1
                    legal = own1(own1 > pt);
                    if ~isempty(legal)
                        chose = legal(1);
                    end
                case 2
                    legal = own2(own2 > pt);
                    if ~isempty(legal)
                        chose = repelem(legal(1),2);
                    end
                case 3
                    legal = own3(own3 > pt);
                    if ~isempty(legal)
                        chose = repelem(legal(1),3);
                    end
                case 4
                    legal = own3(own3 > pt);
                    if ~isempty(legal) && ~isempty(own1(own1 ~= legal(1))) %  除了三张以外，能带单
                        addition = own1(own1 ~= legal(1));
                        chose = [repelem(legal(1),3), addition(1)];
                    end
                case 5
                    legal = own3(own3 > pt);
                    if ~isempty(legal) && ~isempty(own2(own2 ~= legal(1))) %  除了三张以外，能带对
                        addition = own2(own2 ~= legal(1));
                        chose = [repelem(legal(1),3), repelem(addition(1),2)];
                    end
                case 6
                    for i = pt+1 : 13-len
                        if sum(bucket(i : i+len-1)<1) == 0  %  顺子需要的点，手里都有至少一张
                            chose = i : i+len-1;
                            break;
                        end
                    end
                case 7
                    for i = pt+1 : 13-len/2
                        if sum(bucket(i : i+len/2-1)<2) == 0
                            chose = repelem(i : i+len/2-1, 2);
                            break;v
                        end
                    end
                case 8
                    for i = pt+1 : 13-len/3
                        if sum(bucket(i : i+len/3-1)<3) == 0
                            chose = repelem(i : i+len/3-1, 3);
                            break;
                        end
                    end
                case 9
                    for i = pt+1 : 13-len/4
                        if sum(bucket(i : i+len/4-1)<3) == 0
                            addition = points(points < i | points > i+len/4-1);
                            if length(addition) >= len/4  %  除了飞机需要的x组三牌，还能找出x个单张
                                chose = [repelem(i : i+len/4-1,3), points(1:len/4)];
                                break;
                            end
                        end
                    end
                case 10
                    for i = pt+1 : 13-len/5
                        if sum(bucket(i : i+len/5-1)<3) == 0
                            addition = own2(own2 < i | own2 > i+len/5-1);
                            if length(addition) >= len/5  %  除了飞机需要的x组三牌，还能找出x个对子
                                chose = [repelem(i : i+len/5-1,3), repelem(own2(1:len/5),2)];
                                break;
                            end
                        end
                    end
                case 11
                    legal = own4(own4 > pt);
                    if ~isempty(legal) && length(points(points ~= legal(1))) >= 2 %  除了四张以外，能带两个单
                        addition = points(points ~= legal(1));
                        chose = [repelem(legal(1),4), addition(end), addition(end-1)];
                    end
                case 12
                    legal = own4(own4 > pt);
                    if ~isempty(legal) && length(own2(own2 ~= legal(1))) >= 2 %  除了四张以外，能带两个对
                        addition = own2(own2 ~= legal(1));
                        chose = [repelem(legal(1),4), repelem(addition(1),2), repelem(addition(2),2)];
                    end
            end
            if strcmp(chose,"pass")  %  如果正常牌型压不住，则在手牌中找炸弹不支持在 double 和 string 之间进行比较。   !!!!不支持在 double 和 string 之间进行比较。
                for i = own4
                    if (13-app.last_played(2))*100 + i-pt > 0
                        chose = repelem(i,4); break
                    end
                end
            end
            if strcmp(chose,"pass") && length(points(points > 13)) == 2  %  最后在手牌里找王炸
                chose = [14,15];
            end
            if ~strcmp(chose,"pass")  %  根据chose，输出所选手牌的序号
                chose = sort(chose);
                for i = 1:length(points)
                    if ~isempty(chose) && points(i) == chose(end)
                        chose = chose(1:end - 1);
                        results(end + 1) = i;
                    end
                end
            end
        end
        
        function results = validate(app,playerid)   % 判断出牌是否合法的函数，输入牌组序号，返回为 牌型和出的牌字符串
            results = "ok";
            pt = 0; typeid = 0;
            len = length(app.selected);
            if len == 0
                results = "invalid"; return
            elseif app.selected == 0
                results = "不出"; return
            end
            % 牌型序号： 1    2      3      4        5      6      7     8      9     10      11       12       13    14
            % 牌数分别为：1    2     3      4        5      5+  (3+)*2 (2+)*3（2+)*4（2+)*5     6       8        4      2 
            type = ["一张","对子","三张","三带一","三带对","顺子","连对","三顺","飞机","飞机","四带二","四带两对","炸弹","王炸"];
            points = app.card_get_point(app.selected);
            bucket = zeros(1,15);       %  每个点数出的张数
            for i = points
                bucket(i) = bucket(i) + 1;
            end
            find1 = find(bucket == 1);  %  出了一张的点数
            find2 = find(bucket == 2);  %  出了两张的点数
            find3 = find(bucket == 3);  %  出了三张的点数
            if len == 1   % 单张
                typeid = 1; pt = find1(1);
            elseif len == 2 && sum(app.selected) == 107  %  王炸
                typeid = 14;
            elseif len == 2 && points(1) == points(2)  %  对子
                typeid = 2; pt = find2(1);
            elseif len == 3 && length(find3) == 1  %  三张
                typeid = 3; pt = find3(1);
            elseif len == 4 && length(find(bucket == 4)) == 1   %  炸弹
                typeid = 13; pt = points(1);
            elseif len == 4 && length(find3) == 1 && length(find1) == 1  %  三带一
                typeid = 4; pt = find3(1);
            elseif len == 5 && length(find3) == 1 && length(find2) == 1  %  三带对
                typeid = 5; pt = find3(1);
            elseif len == 6 && length(find(bucket == 4)) == 1  %  四带二
                typeid = 11; pt = find(bucket == 4);
            elseif len == 8 && length(find(bucket == 4)) == 1 && length(find2) == 2  %  四带两对
                typeid = 12; pt = find(bucket == 4);
            elseif length(find1) >= 5 && len == find1(end) - find1(1) + 1 && sum(bucket(13:15)) == 0
                %  顺子：不能带有2牌
                typeid = 6; pt = find1(1);
            elseif length(find2) >= 3 && len == (find2(end) - find2(1) + 1) * 2 && sum(bucket(13:15)) == 0
                %  连对：不能带有2牌
                typeid = 7; pt = find2(1);
            elseif length(find3) >= 2 && len == (find3(end) - find3(1) + 1) * 3 && sum(bucket(13:15)) == 0
                %  三顺：不能带有2牌
                typeid = 8; pt = find3(1);
            elseif length(find3)>=2 && len==(find3(end)-find3(1)+1)*4 && length(find3)*4==len && bucket(13)<3
                %  带单型飞机：不能带有2牌
                typeid = 9; pt = find3(1);
            elseif length(find3)>=2 && len==(find3(end)-find3(1)+1)*5 && length(find3)*5==len && isempty(find1) && bucket(13)<3
                %  带对型飞机：不能带有2牌
                typeid = 10; pt = find3(1);
            else
                results = "invalid";
            end
            if results == "ok" && playerid ~= app.last_played(1)  %  如果合法，判断出牌是否大过上家
                if app.last_played(2) > 12 || typeid > 12  %  有炸弹，比较炸弹的大小
                    if (typeid - app.last_played(2))*100 + pt - app.last_played(3) <= 0
                        results = "small";
                    end
                elseif typeid ~= app.last_played(2) || len ~= app.last_played(4)
                    results = "different";
                elseif pt <= app.last_played(3)  %  没有炸弹，需要牌型相同且点数大
                    results = "small";
                end
            end
            if results == "ok"
                if typeid > 12  %  每出一个炸弹，分数翻倍
                    app.mutiplier = app.mutiplier * 2;
                    for j = 1:3
                        eval("app.multiplier_" + num2str(j) + ".Value=""倍数：""+num2str(app.mutiplier);");
                    end
                end
                app.last_played = [playerid, typeid, pt, len];
                results(1) = type(typeid);
                results(2:len+1) = app.cards(app.selected);
            end
        end
        
        function serve_card(app)
            app.shuffle = randperm(54);   % randperm(54),测试改用：1:54
            app.hand1 = sort(app.shuffle(1:17),'descend');
            app.hand2 = sort(app.shuffle(18:34),'descend');
            app.hand3 = sort(app.shuffle(35:51),'descend');
            for i = 1:3  % show the interface for 3 players
                app.update_handcard(i);
                eval("app.leave_"+num2str(i)+".Visible=1;")
                eval("app.leave_"+num2str(i)+".Enable=1;")
                for j = [app.info_button,app.call_button]
                    eval("app." + j + num2str(i) + ".Visible=1;");
                end
                for j = 1:3
                    eval("app.player"+num2str(j)+"card_"+num2str(i)+".Value={''};")
                end
            end
            app.cur_player = ceil(rand(1)*3);
            app.calling = [0,0,0];
            if app.setting.Value
                app.call_for(app.cur_player);
            else
                app.TabGroup.SelectedTab = eval("app.player"+ app.cur_player + "Tab");
            end
        end

        function waitcall_pre(app)
            app.state = app.state + 1;
            if app.landlordID ~= 0
                eval("app.call_" + num2str(app.cur_player) + ".Text=""抢地主"";");
                eval("app.NOcall_" + num2str(app.cur_player) + ".Text=""不抢"";");
            end
            for j = [app.call_button,app.info_button]
                eval("app." + j + num2str(app.cur_player) + ".Enable=1;");
            end
            app.call_info = "";
            if eval("app.leave_" + num2str(app.cur_player) + ".Value")
                pause(0.2);
                app.calling(app.cur_player) = 0;
                app.call_info = eval("app.NOcall_" + num2str(app.cur_player) + ".Text");
                app.waitcall_post();
                if app.state < 10
                    app.waitcall_pre();
                else
                    app.init_game();
                    app.waitplay_pre();
                end
            end
        end
        
        function waitcall_post(app)
            for j = 1:3
                eval("app.player"+num2str(app.cur_player)+"card_"+num2str(j)+".Value={char(app.call_info)};")
            end
            for j = app.call_button
                eval("app." + j + num2str(app.cur_player) + ".Enable=0;");
            end
            app.cur_player = mod(app.cur_player,3) + 1;
            if app.state == 3  % 三人叫过一次地主，考虑不同情况
                if sum(app.calling) == 0
                    app.state = 0;
                    app.serve_card();
                elseif sum(app.calling) > 1
                    if app.calling(app.cur_player) == 0
                        app.cur_player = mod(app.cur_player,3) + 1;
                    end
                else
                    app.state = 10;
                end
            elseif app.state == 4
                app.state = 10;
            end
            if app.setting.Value
                app.call_for(app.cur_player);
            else
                app.TabGroup.SelectedTab = eval("app.player"+ app.cur_player + "Tab");
            end
        end
        
        function init_game(app)    
            for i = 1:3
                eval("app.player"+num2str(app.landlordID)+"info_"+num2str(i)+".Value(2)={'地主'};");
                eval("app.player"+num2str(app.landlordID)+"info_"+num2str(i)+".FontWeight=""bold"";");
                eval("app.player"+num2str(app.landlordID)+"card_"+num2str(i)+".Value(1)={char(app.cards(app.shuffle(52)))};");
                eval("app.player"+num2str(app.landlordID)+"card_"+num2str(i)+".Value(2)={char(app.cards(app.shuffle(53)))};");
                eval("app.player"+num2str(app.landlordID)+"card_"+num2str(i)+".Value(3)={char(app.cards(app.shuffle(54)))};");
            end
            eval("app.hand"+num2str(app.landlordID)+"(18:20)=app.shuffle(52:54);");
            eval("app.hand"+num2str(app.landlordID)+"=sort(app.hand"+num2str(app.landlordID)+",'descend');");
            eval("app.update_handcard("+num2str(app.landlordID)+");");
%-------------------------------------- begin playing ---------------------------------
            app.cur_player = app.landlordID;
            app.last_played(1) = app.cur_player;
            for i = 1:3  %  将操作按键设为可见
                for j = 1:3
                    if j ~= app.landlordID
                        eval("app.player"+num2str(j)+"card_"+num2str(i)+".Value={''};")
                    end
                end
                for j = app.call_button
                    eval("app." + j + num2str(i) + ".Visible=0;");
                    eval("app." + j + num2str(i) + ".Enable=0;");
                end
                for j = app.operate_button
                    eval("app." + j + num2str(i) + ".Visible=1;");
                end
                if app.setting_2.Value
                    record1_data = zeros(1,15);
                    record2_data = zeros(1,15);
                    record3_data = zeros(1,15);
                    for j = app.hand1
                        record2_data(16-app.card_get_point(j))=record2_data(16-app.card_get_point(j))+1;
                        record3_data(16-app.card_get_point(j))=record3_data(16-app.card_get_point(j))+1;
                    end
                    for j = app.hand2
                        record1_data(16-app.card_get_point(j))=record1_data(16-app.card_get_point(j))+1;
                        record3_data(16-app.card_get_point(j))=record3_data(16-app.card_get_point(j))+1;
                    end
                    for j = app.hand3
                        record2_data(16-app.card_get_point(j))=record2_data(16-app.card_get_point(j))+1;
                        record1_data(16-app.card_get_point(j))=record1_data(16-app.card_get_point(j))+1;
                    end
                    app.recorder_1.Data = record1_data; app.recorder_1.Visible = 1; app.recorder_1.Enable = 'on';
                    app.recorder_2.Data = record2_data; app.recorder_2.Visible = 1; app.recorder_2.Enable = 'on';
                    app.recorder_3.Data = record3_data; app.recorder_3.Visible = 1; app.recorder_3.Enable = 'on';
                end
            end
            if app.setting.Value
                app.call_for(app.cur_player);
            else
                app.TabGroup.SelectedTab = eval("app.player"+ app.cur_player + "Tab");
            end
%------------------------------ each player play cards sequently --------------------------
            app.clear = 0;   %  操作开始前，清除即将操作的玩家的上次操作除即将操作的玩家的上次操作
        end
        
        function waitplay_pre(app)
            if app.clear == 1
                for i = 1:3
                    eval("app.player"+num2str(app.cur_player)+"card_"+num2str(i)+".Value={''};")
                end
            end
            app.selected = [];
            for i = app.operate_button
                eval("app." + i + num2str(app.cur_player) + ".Enable=1;");
            end
            if app.cur_player == app.last_played(1)
                eval("app.pass_" + num2str(app.cur_player) + ".Enable=0;");
            end
            if eval("app.leave_" + num2str(app.cur_player) + ".Value")
                pause(0.1);
                ai_select = app.auto_chose(app.cur_player);
                app.selected = eval("app.hand"+num2str(app.cur_player)+"(ai_select)");
                if isempty(ai_select)
                    app.selected = 0;
                end
                for i = 1:length(eval("app.hand"+num2str(app.cur_player)))
                    if eval("app.Button_"+num2str((app.cur_player-1)*20+i)+".Value == 1")
                        app.selected(end+1) = eval("app.hand"+num2str(app.cur_player)+"("+num2str(i)+")");
                    end
                end
                app.play_info = app.validate(app.cur_player);     % validate()是核心函数,若操作合法，返回app.play_info为此玩家的操作
                if eval("app.leave_" + num2str(app.cur_player) + ".Value") && ~isempty(find(["small","invalid","different"]==app.play_info(1), 1))
                    app.play_info = "不出";
                end
                app.waitplay_post();
                if app.state == 20
                    app.end_settle();
                else
                    app.waitplay_pre();
                end
            end
        end
        
        function waitplay_post(app)
            for i = 1:3           % 当前玩家的操作显示给其他玩家
                eval("app.player"+num2str(app.cur_player)+"card_"+num2str(i)+".Value={''};")
                for j = 1:length(app.play_info)
                    eval("app.player"+num2str(app.cur_player)+"card_"+num2str(i)+".Value("+num2str(j)+")={char(app.play_info("+num2str(j)+"))};");
                end
            end
            for i = app.selected
                eval("app.hand"+num2str(app.cur_player)+"=app.hand"+num2str(app.cur_player)+"(find(app.hand"+num2str(app.cur_player)+"~="+num2str(i)+"));");
                if app.setting_2.Value
                    for j = 1:3
                        if j ~= app.cur_player && app.selected(1) ~= 0
                            idx = 16 - app.card_get_point(i);
                            cnt = eval("app.recorder_"+num2str(j)+".Data(idx)");
                            eval("app.recorder_"+num2str(j)+".Data(idx) = cnt-1;")
                        end
                    end
                end
            end
            app.update_handcard(app.cur_player);
            for i = app.operate_button
                eval("app." + i + num2str(app.cur_player) + ".Enable=0;");
            end
            if length(app.hand1)*length(app.hand2)*length(app.hand3) == 0  %  如果该玩家手牌出完，结束游戏
                app.state = 20;
                app.winner = app.cur_player;
            end
            app.cur_player = mod(app.cur_player,3) + 1;    % 轮到下一位玩家出牌
            app.clear = 1;
            if app.setting.Value
                app.call_for(app.cur_player);
            else
                app.TabGroup.SelectedTab = eval("app.player"+ app.cur_player + "Tab");
            end
        end
        
        function end_settle(app)
            game_info = "";
            is_landlord_win = (app.winner == app.landlordID);
            for i = 1:3
                game_info(i,1) = string(eval("app.player"+num2str(i)+"info_1.Value(1)"));
                if app.landlordID == i
                    game_info(i,2) = "地主";
                    if is_landlord_win
                        game_info(i,3) = "胜利";
                        game_info(i,4) = "+" + num2str(app.mutiplier*2);
                        app.usr_id(i+3) = app.usr_id(i+3) + app.mutiplier*2;
                    else
                        game_info(i,3) = "失败";
                        game_info(i,4) = "-" + num2str(app.mutiplier*2);
                        app.usr_id(i+3) = app.usr_id(i+3) - app.mutiplier*2;
                    end
                else
                    game_info(i,2) = "农民";
                    if is_landlord_win
                        game_info(i,3) = "失败";
                        game_info(i,4) = "-" + num2str(app.mutiplier);
                        app.usr_id(i+3) = app.usr_id(i+3) - app.mutiplier;
                    else
                        game_info(i,3) = "胜利";
                        game_info(i,4) = "+" + num2str(app.mutiplier);
                        app.usr_id(i+3) = app.usr_id(i+3) + app.mutiplier;
                    end
                    game_info(i,4) = int16((.5-is_landlord_win)*app.mutiplier*2);
                end
                game_info(i,5) = num2str(app.usr_id(i+3));
            end
%-------------------------------------- update users infomation ---------------------------------
            tmp = {};
            for i = 1:3
                for j = 1:5
                    tmp{i,j} = char(game_info(i,j));
                end
                if app.usr_id(i) ~= 0
                    app.usr_table(app.usr_id(i),2) = table(app.usr_id(i+3));                
                end
            end
            writetable(sortrows(app.usr_table,-2),'player.txt')
            app.TabGroup.SelectedTab = app.TransplayerTab;
            app.NxtPlayer.Visible = 0;
            app.NxtPlayer.Enable = 0;
            app.UITable.Data = tmp;
            app.UITable.Visible = 1;
            app.UITable.Enable = 'on';
            app.end_game.Visible = 1;
            app.end_game.Enable = 1;
            app.end_game2.Visible = 1;
            app.end_game2.Enable = 1;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function START(app, event)
            delete(app.STARTButton);
            app.usr_table = readtable('player.txt');
            app.usr_name = string(app.usr_table.username);
            usr_score = int32(app.usr_table.score);
            app.usr_id = [0,0,0,0,0,0];
%--------------------------------- deal with player infomation --------------------------------------
            for i = 1:3
                eval("app.usr" + num2str(i) + ".Visible=0;");
                eval("app.usr" + num2str(i) + ".Editable=0;");
                eval("app.usr" + num2str(i) + ".Enable=0;");
                eval("app.user_names{" + num2str(i) + "}=app.usr" + num2str(i) + ".Value(1);");
                name = eval("app.usr" + num2str(i) + ".Value(1);");
                if ~isempty(char(name))  %  玩家i不是游客
                    for j = 1:3
                        eval("app.player"+num2str(i)+"info_"+num2str(j)+".Value(1)=name;");
                    end
                    if ~isempty(find(strcmp(app.usr_name, string(name)),1))  %  玩家i为老用户
                        app.usr_id(i) = find(strcmp(app.usr_name, string(name)));
                        app.usr_id(i+3) = usr_score(app.usr_id(i));
                        for j = 1:3
                            eval("app.player"+num2str(i)+"info_"+num2str(j)+".Value(5)={char(""分数：""+num2str(usr_score(app.usr_id(i))))};");
                        end
                    else
                        tbl_size = height(app.usr_table) + 1;
                        app.usr_id(i) = tbl_size;
                        app.usr_table(tbl_size,1) = name;
                    end
                end
            end
            delete(app.usr1);
            delete(app.usr2);
            delete(app.usr3);
            delete(app.usr_tag);
            app.setting.Enable = 0;
            app.setting.Visible = 0;
            app.setting_2.Enable = 0;
            app.setting_2.Visible = 0;
            app.NxtPlayer.Enable = app.setting.Value;
            app.NxtPlayer.Visible = app.setting.Value;
            app.call_button = ["call_","NOcall_"];
            app.operate_button = ["play_","cancel_","tip_","pass_"];  % 增加提示按键 "tip"
            app.info_button = ["multiplier_","player1info_","player2info_","player3info_","player1card_","player2card_","player3card_"];
%------------------------------------- initialize cards ----------------------------------------------
            for i = 1:13
                for j = 1:4
                    app.cards((i-1)*4+j) = app.point(i) + app.suit(j);
                end
            end
            app.cards(53) = "joker";app.cards(54) = "JOKER";
            app.serve_card();
            app.waitcall_pre();
        end

        % Button pushed function: call_1, call_2, call_3
        function call_landlord(app, event)
            app.mutiplier = app.mutiplier * (2-(app.landlordID == 0));
            app.call_info = eval("app.call_" + num2str(app.cur_player) + ".Text");
            for j = 1:3
                eval("app.multiplier_" + num2str(j) + ".Value=""倍数：""+num2str(app.mutiplier);");
            end
            app.calling(app.cur_player) = 1;
            app.landlordID = int32(app.cur_player);
            app.waitcall_post();
            if app.state < 10
                app.waitcall_pre();
            else
                app.init_game();
                app.waitplay_pre();
            end
        end

        % Button pushed function: NOcall_1, NOcall_2, NOcall_3
        function NOcall_landlord(app, event)
            app.calling(app.cur_player) = 0;
            app.call_info = eval("app.NOcall_" + num2str(app.cur_player) + ".Text");
            app.waitcall_post();
            if app.state < 10
                app.waitcall_pre();
            else
                app.init_game();
                app.waitplay_pre();
            end
        end

        % Button pushed function: play_1, play_2, play_3
        function play(app, event)
            for i = 1:length(eval("app.hand"+num2str(app.cur_player)))
                if eval("app.Button_"+num2str((app.cur_player-1)*20+i)+".Value == 1")
                    app.selected(end+1) = eval("app.hand"+num2str(app.cur_player)+"("+num2str(i)+")");
                end
            end
            app.play_info = app.validate(app.cur_player);     % validate()是核心函数,若操作合法，返回app.play_info为此玩家的操作
            if app.play_info == "small"
                eval("app.player"+num2str(app.cur_player)+"card_"+num2str(app.cur_player)+".Value={'您选择的牌未大过上家，请重新选择'};");
                app.clear = 0;
            elseif app.play_info == "invalid"
                eval("app.player"+num2str(app.cur_player)+"card_"+num2str(app.cur_player)+".Value={'您选择的牌不符合规则，请重新选择'};");
                app.clear = 0;
            elseif app.play_info == "different"
                eval("app.player"+num2str(app.cur_player)+"card_"+num2str(app.cur_player)+".Value={'您选择的牌型不正确，请重新选择'};");
                app.clear = 0;
            else
                app.waitplay_post();
            end
            if app.state == 20
                app.end_settle();
            else
                app.waitplay_pre();
            end
        end

        % Button pushed function: cancel_1, cancel_2, cancel_3
        function cancel(app, event)
            for i = 1:20
                eval("app.Button_"+num2str((app.cur_player-1)*20+i)+".Value = 0;")
            end
        end

        % Button pushed function: pass_1, pass_2, pass_3
        function pass(app, event)
            app.selected = 0;
            app.waitplay_post();
            app.waitplay_pre();
        end

        % Button pushed function: NxtPlayer
        function called(app, event)
            id = char(app.NxtPlayer.Text);
            id = id(6);
            app.TabGroup.SelectedTab = eval("app.player"+ id + "Tab");
        end

        % Button pushed function: end_game2
        function more_game(app, event)
            new = FightTheLandlord;
            new.usr1.Value = app.user_names{1};
            new.usr2.Value = app.user_names{2};
            new.usr3.Value = app.user_names{3};
            new.setting.Value = app.setting.Value;
            new.setting_2.Value = app.setting_2.Value;
            delete(app);
        end

        % Button pushed function: tip_1, tip_2, tip_3
        function tip(app, event)
            ai_select = app.auto_chose(app.cur_player);
            for i = 1:20
                eval("app.Button_"+num2str((app.cur_player-1)*20+i)+".Value = 0;")
            end
            if isempty(ai_select)
                eval("app.player"+num2str(app.cur_player)+"card_"+num2str(app.cur_player)+".Value={'您没有牌大过上家'};");
                app.clear = 0;
            else
                for i = ai_select
                    eval("app.Button_"+num2str((app.cur_player-1)*20+i)+".Value = 1;")
                end
            end
        end

        % Value changed function: leave_1
        function leave1(app, event)
            value = app.leave_1.Value;
            button_str = [repelem('取消',value), '托管'];
            app.leave_1.Text = button_str;
            info_str = repelem('托管中', value);
            app.player1info_1.Value(4) = {info_str};
            app.player1info_2.Value(4) = {info_str};
            app.player1info_3.Value(4) = {info_str};
            if app.cur_player == 1 && value
                pause(0.1);
                if app.call_1.Enable(2) == 'n'
                    app.calling(app.cur_player) = 0;
                    app.call_info = eval("app.NOcall_" + num2str(app.cur_player) + ".Text");
                    app.waitcall_post();
                    if app.state < 10
                        app.waitcall_pre();
                    else
                        app.init_game();
                        app.waitplay_pre();
                    end
                else
                    ai_select = app.auto_chose(app.cur_player);
                    app.selected = eval("app.hand"+num2str(app.cur_player)+"(ai_select)");
                    for i = 1:length(eval("app.hand"+num2str(app.cur_player)))
                        if eval("app.Button_"+num2str((app.cur_player-1)*20+i)+".Value == 1")
                            app.selected(end+1) = eval("app.hand"+num2str(app.cur_player)+"("+num2str(i)+")");
                        end
                    end
                    app.play_info = app.validate(app.cur_player);
                    if isempty(ai_select)
                        app.play_info = "不出";
                    end
                    app.waitplay_post();
                    if app.state == 20
                        app.end_settle();
                    else
                        app.waitplay_pre();
                    end
                end
            end
        end

        % Value changed function: leave_2
        function leave2(app, event)
            value = app.leave_2.Value;
            button_str = [repelem('取消',value), '托管'];
            app.leave_2.Text = button_str;
            info_str = repelem('托管中', value);
            app.player2info_1.Value(4) = {info_str};
            app.player2info_2.Value(4) = {info_str};
            app.player2info_3.Value(4) = {info_str};
            if app.cur_player == 2 && value
                pause(0.1);
                if app.call_2.Enable(2) == 'n'
                    app.calling(app.cur_player) = 0;
                    app.call_info = eval("app.NOcall_" + num2str(app.cur_player) + ".Text");
                    app.waitcall_post();
                    if app.state < 10
                        app.waitcall_pre();
                    else
                        app.init_game();
                        app.waitplay_pre();
                    end
                else
                    ai_select = app.auto_chose(app.cur_player);
                    app.selected = eval("app.hand"+num2str(app.cur_player)+"(ai_select)");
                    for i = 1:length(eval("app.hand"+num2str(app.cur_player)))
                        if eval("app.Button_"+num2str((app.cur_player-1)*20+i)+".Value == 1")
                            app.selected(end+1) = eval("app.hand"+num2str(app.cur_player)+"("+num2str(i)+")");
                        end
                    end
                    app.play_info = app.validate(app.cur_player);
                    if isempty(ai_select)
                        app.play_info = "不出";
                    end
                    app.waitplay_post();
                    if app.state == 20
                        app.end_settle();
                    else
                        app.waitplay_pre();
                    end
                end
            end
        end

        % Value changed function: leave_3
        function leave3(app, event)
            value = app.leave_3.Value;
            button_str = [repelem('取消',value), '托管'];
            app.leave_3.Text = button_str;
            info_str = repelem('托管中', value);
            app.player3info_1.Value(4) = {info_str};
            app.player3info_2.Value(4) = {info_str};
            app.player3info_3.Value(4) = {info_str};
            if app.cur_player == 3 && value
                pause(0.1);
                if app.call_3.Enable(2) == 'n'
                    app.calling(app.cur_player) = 0;
                    app.call_info = eval("app.NOcall_" + num2str(app.cur_player) + ".Text");
                    app.waitcall_post();
                    if app.state < 10
                        app.waitcall_pre();
                    else
                        app.init_game();
                        app.waitplay_pre();
                    end
                else
                    ai_select = app.auto_chose(app.cur_player);
                    app.selected = eval("app.hand"+num2str(app.cur_player)+"(ai_select)");
                    for i = 1:length(eval("app.hand"+num2str(app.cur_player)))
                        if eval("app.Button_"+num2str((app.cur_player-1)*20+i)+".Value == 1")
                            app.selected(end+1) = eval("app.hand"+num2str(app.cur_player)+"("+num2str(i)+")");
                        end
                    end
                    app.play_info = app.validate(app.cur_player);
                    if isempty(ai_select)
                        app.play_info = "不出";
                    end
                    app.waitplay_post();
                    if app.state == 20
                        app.end_settle();
                    else
                        app.waitplay_pre();
                    end
                end
            end
        end

        % Button pushed function: end_game
        function end_game_(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.player1Tab = uitab(app.TabGroup);
            app.player1Tab.Title = 'player1';
            app.STARTButton = uibutton(app.player1Tab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @START, true);
            app.STARTButton.Text = '开   始';
            app.Button_1 = uibutton(app.player1Tab, 'state');
            app.Button_1.Enable = 'off';
            app.Button_1.Visible = 'off';
            app.Button_1.Tooltip = {''};
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.player1Tab, 'state');
            app.Button_2.Enable = 'off';
            app.Button_2.Visible = 'off';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.player1Tab, 'state');
            app.Button_3.Enable = 'off';
            app.Button_3.Visible = 'off';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.player1Tab, 'state');
            app.Button_4.Enable = 'off';
            app.Button_4.Visible = 'off';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.player1Tab, 'state');
            app.Button_5.Enable = 'off';
            app.Button_5.Visible = 'off';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.player1Tab, 'state');
            app.Button_6.Enable = 'off';
            app.Button_6.Visible = 'off';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.player1Tab, 'state');
            app.Button_7.Enable = 'off';
            app.Button_7.Visible = 'off';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.player1Tab, 'state');
            app.Button_8.Enable = 'off';
            app.Button_8.Visible = 'off';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.player1Tab, 'state');
            app.Button_9.Enable = 'off';
            app.Button_9.Visible = 'off';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.player1Tab, 'state');
            app.Button_10.Enable = 'off';
            app.Button_10.Visible = 'off';
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.player1Tab, 'state');
            app.Button_11.Enable = 'off';
            app.Button_11.Visible = 'off';
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.player1Tab, 'state');
            app.Button_12.Enable = 'off';
            app.Button_12.Visible = 'off';
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.player1Tab, 'state');
            app.Button_13.Enable = 'off';
            app.Button_13.Visible = 'off';
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.player1Tab, 'state');
            app.Button_14.Enable = 'off';
            app.Button_14.Visible = 'off';
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.player1Tab, 'state');
            app.Button_15.Enable = 'off';
            app.Button_15.Visible = 'off';
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.player1Tab, 'state');
            app.Button_16.Enable = 'off';
            app.Button_16.Visible = 'off';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.player1Tab, 'state');
            app.Button_17.Enable = 'off';
            app.Button_17.Visible = 'off';
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.player1Tab, 'state');
            app.Button_18.Enable = 'off';
            app.Button_18.Visible = 'off';
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.player1Tab, 'state');
            app.Button_19.Enable = 'off';
            app.Button_19.Visible = 'off';
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.player1Tab, 'state');
            app.Button_20.Enable = 'off';
            app.Button_20.Visible = 'off';
            app.Button_20.Text = '';
            app.play_1 = uibutton(app.player1Tab, 'push');
            app.play_1.ButtonPushedFcn = createCallbackFcn(app, @play, true);
            app.play_1.Enable = 'off';
            app.play_1.Visible = 'off';
            app.play_1.Text = '确定';
            app.cancel_1 = uibutton(app.player1Tab, 'push');
            app.cancel_1.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.cancel_1.Enable = 'off';
            app.cancel_1.Visible = 'off';
            app.cancel_1.Text = '取消';
            app.tip_1 = uibutton(app.player1Tab, 'push');
            app.tip_1.ButtonPushedFcn = createCallbackFcn(app, @tip, true);
            app.tip_1.Enable = 'off';
            app.tip_1.Visible = 'off';
            app.tip_1.Text = '提示';
            app.pass_1 = uibutton(app.player1Tab, 'push');
            app.pass_1.ButtonPushedFcn = createCallbackFcn(app, @pass, true);
            app.pass_1.Enable = 'off';
            app.pass_1.Visible = 'off';
            app.pass_1.Text = '不出';
            app.player1card_1 = uitextarea(app.player1Tab);
            app.player1card_1.Editable = 'off';
            app.player1card_1.Enable = 'off';
            app.player1card_1.Visible = 'off';
            app.player2card_1 = uitextarea(app.player1Tab);
            app.player2card_1.Editable = 'off';
            app.player2card_1.Enable = 'off';
            app.player2card_1.Visible = 'off';
            app.player3card_1 = uitextarea(app.player1Tab);
            app.player3card_1.Editable = 'off';
            app.player3card_1.Enable = 'off';
            app.player3card_1.Visible = 'off';
            app.multiplier_1 = uitextarea(app.player1Tab);
            app.multiplier_1.Editable = 'off';
            app.multiplier_1.Visible = 'off';
            app.multiplier_1.Value = {'倍数：1'};
            app.call_1 = uibutton(app.player1Tab, 'push');
            app.call_1.ButtonPushedFcn = createCallbackFcn(app, @call_landlord, true);
            app.call_1.Enable = 'off';
            app.call_1.Visible = 'off';
            app.call_1.Text = '叫地主';
            app.NOcall_1 = uibutton(app.player1Tab, 'push');
            app.NOcall_1.ButtonPushedFcn = createCallbackFcn(app, @NOcall_landlord, true);
            app.NOcall_1.Enable = 'off';
            app.NOcall_1.Visible = 'off';
            app.NOcall_1.Text = '不叫';
            app.setting = uicheckbox(app.player1Tab);
            app.setting.Text = '自动隐藏玩家手牌';
            app.setting.Value = true;
            app.player1info_1 = uitextarea(app.player1Tab);
            app.player1info_1.Editable = 'off';
            app.player1info_1.Enable = 'off';
            app.player1info_1.Visible = 'off';
            app.player1info_1.Value = {'玩家1'; '农民'; '17'; ''; '分数：0'};
            app.player2info_1 = uitextarea(app.player1Tab);
            app.player2info_1.Editable = 'off';
            app.player2info_1.Enable = 'off';
            app.player2info_1.Visible = 'off';
            app.player2info_1.Value = {'玩家2'; '农民'; '17'; ''; '分数：0'};
            app.player3info_1 = uitextarea(app.player1Tab);
            app.player3info_1.Editable = 'off';
            app.player3info_1.Enable = 'off';
            app.player3info_1.Visible = 'off';
            app.player3info_1.Value = {'玩家3'; '农民'; '17'; ''; '分数：0'};
            app.usr1 = uitextarea(app.player1Tab);
            app.usr2 = uitextarea(app.player1Tab);
            app.usr3 = uitextarea(app.player1Tab);
            app.usr_tag = uilabel(app.player1Tab);
            app.usr_tag.Text = {'请输入玩家用户名'; '（注：输入新用户名将自动建立新用户，若不输入用户名，视为游客进行游戏）'; ''; ''; '玩家1                                                           玩家2                                                               玩家3'};
            app.leave_1 = uibutton(app.player1Tab, 'state');
            app.leave_1.ValueChangedFcn = createCallbackFcn(app, @leave1, true);
            app.leave_1.Enable = 'off';
            app.leave_1.Visible = 'off';
            app.leave_1.Text = '托管';
            app.recorder_1 = uitable(app.player1Tab);
            app.recorder_1.ColumnName = {'大王'; '小王'; '2'; 'A'; 'K'; 'Q'; 'J'; '10'; '9'; '8'; '7'; '6'; '5'; '4'; '3'};
            app.recorder_1.RowName = {};
            app.recorder_1.Enable = 'off';
            app.recorder_1.Visible = 'off';
            app.setting_2 = uicheckbox(app.player1Tab);
            app.setting_2.Text = '开启记牌器';
            app.player2Tab = uitab(app.TabGroup);
            app.player2Tab.Title = 'player2';
            app.Button_21 = uibutton(app.player2Tab, 'state');
            app.Button_21.Enable = 'off';
            app.Button_21.Visible = 'off';
            app.Button_21.Tooltip = {''};
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.player2Tab, 'state');
            app.Button_22.Enable = 'off';
            app.Button_22.Visible = 'off';
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.player2Tab, 'state');
            app.Button_23.Enable = 'off';
            app.Button_23.Visible = 'off';
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.player2Tab, 'state');
            app.Button_24.Enable = 'off';
            app.Button_24.Visible = 'off';
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.player2Tab, 'state');
            app.Button_25.Enable = 'off';
            app.Button_25.Visible = 'off';
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.player2Tab, 'state');
            app.Button_26.Enable = 'off';
            app.Button_26.Visible = 'off';
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.player2Tab, 'state');
            app.Button_27.Enable = 'off';
            app.Button_27.Visible = 'off';
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.player2Tab, 'state');
            app.Button_28.Enable = 'off';
            app.Button_28.Visible = 'off';
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.player2Tab, 'state');
            app.Button_29.Enable = 'off';
            app.Button_29.Visible = 'off';
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.player2Tab, 'state');
            app.Button_30.Enable = 'off';
            app.Button_30.Visible = 'off';
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.player2Tab, 'state');
            app.Button_31.Enable = 'off';
            app.Button_31.Visible = 'off';
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.player2Tab, 'state');
            app.Button_32.Enable = 'off';
            app.Button_32.Visible = 'off';
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.player2Tab, 'state');
            app.Button_33.Enable = 'off';
            app.Button_33.Visible = 'off';
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.player2Tab, 'state');
            app.Button_34.Enable = 'off';
            app.Button_34.Visible = 'off';
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.player2Tab, 'state');
            app.Button_35.Enable = 'off';
            app.Button_35.Visible = 'off';
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.player2Tab, 'state');
            app.Button_36.Enable = 'off';
            app.Button_36.Visible = 'off';
            app.Button_36.Text = '';
            app.Button_37 = uibutton(app.player2Tab, 'state');
            app.Button_37.Enable = 'off';
            app.Button_37.Visible = 'off';
            app.Button_37.Text = '';
            app.Button_38 = uibutton(app.player2Tab, 'state');
            app.Button_38.Enable = 'off';
            app.Button_38.Visible = 'off';
            app.Button_38.Text = '';
            app.Button_39 = uibutton(app.player2Tab, 'state');
            app.Button_39.Enable = 'off';
            app.Button_39.Visible = 'off';
            app.Button_39.Text = '';
            app.Button_40 = uibutton(app.player2Tab, 'state');
            app.Button_40.Enable = 'off';
            app.Button_40.Visible = 'off';
            app.Button_40.Text = '';
            app.play_2 = uibutton(app.player2Tab, 'push');
            app.play_2.ButtonPushedFcn = createCallbackFcn(app, @play, true);
            app.play_2.Enable = 'off';
            app.play_2.Visible = 'off';
            app.play_2.Text = '确定';
            app.cancel_2 = uibutton(app.player2Tab, 'push');
            app.cancel_2.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.cancel_2.Enable = 'off';
            app.cancel_2.Visible = 'off';
            app.cancel_2.Text = '取消';
            app.tip_2 = uibutton(app.player2Tab, 'push');
            app.tip_2.ButtonPushedFcn = createCallbackFcn(app, @tip, true);
            app.tip_2.Enable = 'off';
            app.tip_2.Visible = 'off';
            app.tip_2.Text = '提示';
            app.pass_2 = uibutton(app.player2Tab, 'push');
            app.pass_2.ButtonPushedFcn = createCallbackFcn(app, @pass, true);
            app.pass_2.Enable = 'off';
            app.pass_2.Visible = 'off';
            app.pass_2.Text = '不出';
            app.player2card_2 = uitextarea(app.player2Tab);
            app.player2card_2.Editable = 'off';
            app.player2card_2.Enable = 'off';
            app.player2card_2.Visible = 'off';
            app.player3card_2 = uitextarea(app.player2Tab);
            app.player3card_2.Editable = 'off';
            app.player3card_2.Enable = 'off';
            app.player3card_2.Visible = 'off';
            app.player1card_2 = uitextarea(app.player2Tab);
            app.player1card_2.Editable = 'off';
            app.player1card_2.Enable = 'off';
            app.player1card_2.Visible = 'off';
            app.multiplier_2 = uitextarea(app.player2Tab);
            app.multiplier_2.Editable = 'off';
            app.multiplier_2.Visible = 'off';
            app.multiplier_2.Value = {'倍数：1'};
            app.call_2 = uibutton(app.player2Tab, 'push');
            app.call_2.ButtonPushedFcn = createCallbackFcn(app, @call_landlord, true);
            app.call_2.Enable = 'off';
            app.call_2.Visible = 'off';
            app.call_2.Text = '叫地主';
            app.NOcall_2 = uibutton(app.player2Tab, 'push');
            app.NOcall_2.ButtonPushedFcn = createCallbackFcn(app, @NOcall_landlord, true);
            app.NOcall_2.Enable = 'off';
            app.NOcall_2.Visible = 'off';
            app.NOcall_2.Text = '不叫';
            app.player2info_2 = uitextarea(app.player2Tab);
            app.player2info_2.Editable = 'off';
            app.player2info_2.Enable = 'off';
            app.player2info_2.Visible = 'off';
            app.player2info_2.Value = {'玩家2'; '农民'; '17'; ''; '分数：0'};
            app.player3info_2 = uitextarea(app.player2Tab);
            app.player3info_2.Editable = 'off';
            app.player3info_2.Enable = 'off';
            app.player3info_2.Visible = 'off';
            app.player3info_2.Value = {'玩家3'; '农民'; '17'; ''; '分数：0'};
            app.player1info_2 = uitextarea(app.player2Tab);
            app.player1info_2.Editable = 'off';
            app.player1info_2.Enable = 'off';
            app.player1info_2.Visible = 'off';
            app.player1info_2.Value = {'玩家1'; '农民'; '17'; ''; '分数：0'};
            app.leave_2 = uibutton(app.player2Tab, 'state');
            app.leave_2.ValueChangedFcn = createCallbackFcn(app, @leave2, true);
            app.leave_2.Enable = 'off';
            app.leave_2.Visible = 'off';
            app.leave_2.Text = '托管';
            app.recorder_2 = uitable(app.player2Tab);
            app.recorder_2.ColumnName = {'大王'; '小王'; '2'; 'A'; 'K'; 'Q'; 'J'; '10'; '9'; '8'; '7'; '6'; '5'; '4'; '3'};
            app.recorder_2.RowName = {};
            app.recorder_2.Enable = 'off';
            app.recorder_2.Visible = 'off';
            app.player3Tab = uitab(app.TabGroup);
            app.player3Tab.Title = 'player3';
            app.Button_41 = uibutton(app.player3Tab, 'state');
            app.Button_41.Enable = 'off';
            app.Button_41.Visible = 'off';
            app.Button_41.Tooltip = {''};
            app.Button_41.Text = '';
            app.Button_42 = uibutton(app.player3Tab, 'state');
            app.Button_42.Enable = 'off';
            app.Button_42.Visible = 'off';
            app.Button_42.Text = '';
            app.Button_43 = uibutton(app.player3Tab, 'state');
            app.Button_43.Enable = 'off';
            app.Button_43.Visible = 'off';
            app.Button_43.Text = '';
            app.Button_44 = uibutton(app.player3Tab, 'state');
            app.Button_44.Enable = 'off';
            app.Button_44.Visible = 'off';
            app.Button_44.Text = '';
            app.Button_45 = uibutton(app.player3Tab, 'state');
            app.Button_45.Enable = 'off';
            app.Button_45.Visible = 'off';
            app.Button_45.Text = '';
            app.Button_46 = uibutton(app.player3Tab, 'state');
            app.Button_46.Enable = 'off';
            app.Button_46.Visible = 'off';
            app.Button_46.Text = '';
            app.Button_47 = uibutton(app.player3Tab, 'state');
            app.Button_47.Enable = 'off';
            app.Button_47.Visible = 'off';
            app.Button_47.Text = '';
            app.Button_48 = uibutton(app.player3Tab, 'state');
            app.Button_48.Enable = 'off';
            app.Button_48.Visible = 'off';
            app.Button_48.Text = '';
            app.Button_49 = uibutton(app.player3Tab, 'state');
            app.Button_49.Enable = 'off';
            app.Button_49.Visible = 'off';
            app.Button_49.Text = '';
            app.Button_50 = uibutton(app.player3Tab, 'state');
            app.Button_50.Enable = 'off';
            app.Button_50.Visible = 'off';
            app.Button_50.Text = '';
            app.Button_51 = uibutton(app.player3Tab, 'state');
            app.Button_51.Enable = 'off';
            app.Button_51.Visible = 'off';
            app.Button_51.Text = '';
            app.Button_52 = uibutton(app.player3Tab, 'state');
            app.Button_52.Enable = 'off';
            app.Button_52.Visible = 'off';
            app.Button_52.Text = '';
            app.Button_53 = uibutton(app.player3Tab, 'state');
            app.Button_53.Enable = 'off';
            app.Button_53.Visible = 'off';
            app.Button_53.Text = '';
            app.Button_54 = uibutton(app.player3Tab, 'state');
            app.Button_54.Enable = 'off';
            app.Button_54.Visible = 'off';
            app.Button_54.Text = '';
            app.Button_55 = uibutton(app.player3Tab, 'state');
            app.Button_55.Enable = 'off';
            app.Button_55.Visible = 'off';
            app.Button_55.Text = '';
            app.Button_56 = uibutton(app.player3Tab, 'state');
            app.Button_56.Enable = 'off';
            app.Button_56.Visible = 'off';
            app.Button_56.Text = '';
            app.Button_57 = uibutton(app.player3Tab, 'state');
            app.Button_57.Enable = 'off';
            app.Button_57.Visible = 'off';
            app.Button_57.Text = '';
            app.Button_58 = uibutton(app.player3Tab, 'state');
            app.Button_58.Enable = 'off';
            app.Button_58.Visible = 'off';
            app.Button_58.Text = '';
            app.Button_59 = uibutton(app.player3Tab, 'state');
            app.Button_59.Enable = 'off';
            app.Button_59.Visible = 'off';
            app.Button_59.Text = '';
            app.Button_60 = uibutton(app.player3Tab, 'state');
            app.Button_60.Enable = 'off';
            app.Button_60.Visible = 'off';
            app.Button_60.Text = '';
            app.play_3 = uibutton(app.player3Tab, 'push');
            app.play_3.ButtonPushedFcn = createCallbackFcn(app, @play, true);
            app.play_3.Enable = 'off';
            app.play_3.Visible = 'off';
            app.play_3.Text = '确定';
            app.cancel_3 = uibutton(app.player3Tab, 'push');
            app.cancel_3.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.cancel_3.Enable = 'off';
            app.cancel_3.Visible = 'off';
            app.cancel_3.Text = '取消';
            app.tip_3 = uibutton(app.player3Tab, 'push');
            app.tip_3.ButtonPushedFcn = createCallbackFcn(app, @tip, true);
            app.tip_3.Enable = 'off';
            app.tip_3.Visible = 'off';
            app.tip_3.Text = '提示';
            app.pass_3 = uibutton(app.player3Tab, 'push');
            app.pass_3.ButtonPushedFcn = createCallbackFcn(app, @pass, true);
            app.pass_3.Enable = 'off';
            app.pass_3.Visible = 'off';
            app.pass_3.Text = '不出';
            app.player3card_3 = uitextarea(app.player3Tab);
            app.player3card_3.Editable = 'off';
            app.player3card_3.Enable = 'off';
            app.player3card_3.Visible = 'off';
            app.player1card_3 = uitextarea(app.player3Tab);
            app.player1card_3.Editable = 'off';
            app.player1card_3.Enable = 'off';
            app.player1card_3.Visible = 'off';
            app.player2card_3 = uitextarea(app.player3Tab);
            app.player2card_3.Editable = 'off';
            app.player2card_3.Enable = 'off';
            app.player2card_3.Visible = 'off';
            app.multiplier_3 = uitextarea(app.player3Tab);
            app.multiplier_3.Editable = 'off';
            app.multiplier_3.Visible = 'off';
            app.multiplier_3.Value = {'倍数：1'};
            app.call_3 = uibutton(app.player3Tab, 'push');
            app.call_3.ButtonPushedFcn = createCallbackFcn(app, @call_landlord, true);
            app.call_3.Enable = 'off';
            app.call_3.Visible = 'off';
            app.call_3.Text = '叫地主';
            app.NOcall_3 = uibutton(app.player3Tab, 'push');
            app.NOcall_3.ButtonPushedFcn = createCallbackFcn(app, @NOcall_landlord, true);
            app.NOcall_3.Enable = 'off';
            app.NOcall_3.Visible = 'off';
            app.NOcall_3.Text = '不叫';
            app.player3info_3 = uitextarea(app.player3Tab);
            app.player3info_3.Editable = 'off';
            app.player3info_3.Enable = 'off';
            app.player3info_3.Visible = 'off';
            app.player3info_3.Value = {'玩家3'; '农民'; '17'; ''; '分数：0'};
            app.player1info_3 = uitextarea(app.player3Tab);
            app.player1info_3.Editable = 'off';
            app.player1info_3.Enable = 'off';
            app.player1info_3.Visible = 'off';
            app.player1info_3.Value = {'玩家1'; '农民'; '17'; ''; '分数：0'};
            app.player2info_3 = uitextarea(app.player3Tab);
            app.player2info_3.Editable = 'off';
            app.player2info_3.Enable = 'off';
            app.player2info_3.Visible = 'off';
            app.player2info_3.Value = {'玩家2'; '农民'; '17'; ''; '分数：0'};
            app.leave_3 = uibutton(app.player3Tab, 'state');
            app.leave_3.ValueChangedFcn = createCallbackFcn(app, @leave3, true);
            app.leave_3.Enable = 'off';
            app.leave_3.Visible = 'off';
            app.leave_3.Text = '托管';
            app.recorder_3 = uitable(app.player3Tab);
            app.recorder_3.ColumnName = {'大王'; '小王'; '2'; 'A'; 'K'; 'Q'; 'J'; '10'; '9'; '8'; '7'; '6'; '5'; '4'; '3'};
            app.recorder_3.RowName = {};
            app.recorder_3.Enable = 'off';
            app.recorder_3.Visible = 'off';
            app.TransplayerTab = uitab(app.TabGroup);
            app.TransplayerTab.Title = 'TransplayerTab';
            app.NxtPlayer = uibutton(app.TransplayerTab, 'push');
            app.NxtPlayer.ButtonPushedFcn = createCallbackFcn(app, @called, true);
            app.NxtPlayer.Enable = 'off';
            app.NxtPlayer.Text = '请在玩家1界面点击“开始”';
            app.UITable = uitable(app.TransplayerTab);
            app.UITable.ColumnName = {'玩家'; '身份'; '胜利/失败'; '本局得分'; '总分数'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.UITable.Visible = 'off';
            app.end_game = uibutton(app.TransplayerTab, 'push');
            app.end_game.ButtonPushedFcn = createCallbackFcn(app, @end_game_, true);
            app.end_game.Enable = 'off';
            app.end_game.Visible = 'off';
            app.end_game.Text = '退出';
            app.end_game2 = uibutton(app.TransplayerTab, 'push');
            app.end_game2.ButtonPushedFcn = createCallbackFcn(app, @more_game, true);
            app.end_game2.Enable = 'off';
            app.end_game2.Visible = 'off';
            app.end_game2.Text = '再来一局';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = version2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end