classdef player1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        InfoBox              matlab.ui.container.Panel
        Message              matlab.ui.control.Label
        BeLandlord           matlab.ui.control.Button
        NotBeLandlord        matlab.ui.control.Button
        Button_1             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        Button_9             matlab.ui.control.Button
        Button_10            matlab.ui.control.Button
        Button_11            matlab.ui.control.Button
        Button_12            matlab.ui.control.Button
        Button_13            matlab.ui.control.Button
        Button_14            matlab.ui.control.Button
        Button_15            matlab.ui.control.Button
        Button_16            matlab.ui.control.Button
        Button_17            matlab.ui.control.Button
        Button_18            matlab.ui.control.Button
        Button_19            matlab.ui.control.Button
        Button_20            matlab.ui.control.Button
        player2Label         matlab.ui.control.Label
        playerRightTextArea  matlab.ui.control.TextArea
        Image                matlab.ui.control.Image
        PlaytheHand          matlab.ui.control.Button
        player3Label         matlab.ui.control.Label
        playerLeftTextArea   matlab.ui.control.TextArea
        Label                matlab.ui.control.Label
        landlord             matlab.ui.control.TextArea
        NotPlaytheHand       matlab.ui.control.Button
        HintButton           matlab.ui.control.Button
        Image_2              matlab.ui.control.Image
        Image_3              matlab.ui.control.Image
        tuoguanButton        matlab.ui.control.Button
        Button_21            matlab.ui.control.Button
    end

    
    properties (Access = public)
        Game
        islandlord % Description
        ChooseLandlordEnd
        CardButtonPositionBottom 
        ChoosedCardNum
        CardButtonPositionLeftMost
        RemainingCardNum
        MaxCardNum
        CardInterval
        LastCardList
        SuitableCards
        PlaytheHandAgain
        PlaytheHandEnd
        ChoosePlayTheHand
        RoundStarter
        tuoguanstatue
        initialBottom
    end
    
    methods (Access = public)
        
        function callUIFigureCloseRequest(app)
            delete(app);
        end
        
        
        function SetGame(app,game)
            app.Game = game;
        end
        
        function result= GetRemainCardNum(app)
            result = [];
            StrCardList = [];
            for i = 1:20
                button = eval('app.Button_' + string(i));
                if button.Visible == "on"
                    StrCardList = [StrCardList;string(button.Text)];
                end
            end
            result= strlist2numberlist(StrCardList);
        end
        
        function OnlyPlaytheHandOptionOn(app)
            if  ~app.tuoguanstatue
                app.PlaytheHandEnd = false;
                app.PlaytheHand.Visible = 'on';
                app.PlaytheHand.Enable = 'on';   
            else
                app.LastCardList = [0];
                [kind,keycard] = CardListKind(app.LastCardList);
                ProperCardIndex = app.FindProperCards(kind,keycard);
                app.ProperCardsUp(ProperCardIndex);
                if ~isempty(ProperCardIndex)
                    app.PlaytheHandFunc();
                    app.ChoosePlayTheHand = true;
                else
                    app.ChoosePlayTheHand = false;
                end
                app.PlaytheHandEnd = true;
                pause(1);
                app.Game.PlaytheHand_post(app.Game.Process,app.Game.currentplayer,app.Game.currentplayerui);

            end
        end        
        
        function ChooseLandlordOptionOn(app)
            app.ChooseLandlordEnd= false;
            app.BeLandlord.Visible = 'on';
            app.BeLandlord.Enable = 'on';
            app.NotBeLandlord.Visible = 'on';
            app.NotBeLandlord.Enable = 'on';
        end
        
        function PublicUIFigureCloseRequest(app)
            delete(app);
        end
%       传进来的cardlist是一个矩阵，用；分割，默认里面是number 而不是string
%       这个函数可以把所有的cardlist展示出来
%       默认cardlist没有经过排序
        function CardInitialize(app,cardlist)
            numsortcardlist = sortrows(cardlist);
           
            stringsortcardlist = numberlist2strlist(numsortcardlist);
            
            for i = 1:length(stringsortcardlist)
                button = eval('app.Button_' + string(i));
                button.Text = sprintf(string(stringsortcardlist(i)));
            end
            if length(stringsortcardlist) == 17
                app.Button_18.Visible = 'off';
                app.Button_18.Enable = 'off';
                app.Button_19.Visible = 'off';
                app.Button_19.Enable = 'off';
                app.Button_20.Visible = 'off';
                app.Button_20.Enable = 'off';
            else
                app.Button_18.Visible = 'on';
                app.Button_18.Enable = 'on';
                app.Button_19.Visible = 'on';
                app.Button_19.Enable = 'on';
                app.Button_20.Visible = 'on';
                app.Button_20.Enable = 'on';                
            end
        end
        
        function PlaytheHandOptionOn(app)
            if  ~app.tuoguanstatue
                app.PlaytheHandEnd = false;
                app.PlaytheHand.Visible = 'on';
                app.PlaytheHand.Enable = 'on';
                app.NotPlaytheHand.Enable = 'on';
                app.NotPlaytheHand.Visible = 'on';
                app.HintButton.Enable = 'on';
                app.HintButton.Visible = 'on';
            else
                [kind,keycard] = CardListKind(app.LastCardList);
                ProperCardIndex = app.FindProperCards(kind,keycard);
                app.ProperCardsUp(ProperCardIndex);
                if ~isempty(ProperCardIndex)
                    app.PlaytheHandFunc();
                    app.ChoosePlayTheHand = true;
                else
                    app.ChoosePlayTheHand = false;
                end
                app.PlaytheHandEnd = true;
                pause(1);
                app.Game.PlaytheHand_post(app.Game.Process,app.Game.currentplayer,app.Game.currentplayerui);
                
            end
        
            
        end
        
        function PlaytheHandOptionOff(app)
            app.PlaytheHand.Visible = 'off';
            app.PlaytheHand.Enable = 'off';
            app.NotPlaytheHand.Enable = 'off';
            app.NotPlaytheHand.Visible = 'off';      
            app.HintButton.Visible = 'off';
            app.HintButton.Enable = 'off';
            app.PlaytheHandEnd = true;
        end        
        
        function PlaytheHandFunc(app) 
            ButtonUpCardList = app.GetChoosedCardList();
            if isempty(ButtonUpCardList)
                warndlg("请选择合适的牌！");
                app.SuitableCards = false;
            else
                NumCardList = app.ButtonUpListToCardList();
                if app.RoundStarter == false
                    lastcardlist1 = app.LastCardList;
                    if CheckCardSuitable(NumCardList,lastcardlist1)
                        app.ChoosedCardNum = app.ButtonUpListToCardList();
                        for i = 1:length(ButtonUpCardList)
                            HandButton = eval('app.Button_' + string(ButtonUpCardList(i)));
                            HandButton.Visible = 'off';
                            HandButton.Enable = 'off';
                        end                    
                        CurrentStart = app.CardButtonPositionLeftMost;                    
                        for j = 1:app.MaxCardNum
                            VisableButton = eval('app.Button_' + string(j));
                            if app.VisibleCard(VisableButton)
                                VisableButton.Position = [CurrentStart , app.CardButtonPositionBottom , VisableButton.Position(3) , VisableButton.Position(4)];
                                CurrentStart = CurrentStart + app.CardInterval;
                            end
                        end
                        app.PlaytheHandOptionOff();
                    else
                        warndlg("请选择合适的牌！");
                    end
                else
                    [kind,~] = CardListKind(NumCardList);
                    if kind~=0
                        app.ChoosedCardNum = app.ButtonUpListToCardList();
                        for i = 1:length(ButtonUpCardList)
                            HandButton = eval('app.Button_' + string(ButtonUpCardList(i)));
                            HandButton.Visible = 'off';
                            HandButton.Enable = 'off';
                        end                    
                        CurrentStart = app.CardButtonPositionLeftMost;                    
                        for j = 1:app.MaxCardNum
                            VisableButton = eval('app.Button_' + string(j));
                            if app.VisibleCard(VisableButton)
                                VisableButton.Position = [CurrentStart , app.CardButtonPositionBottom , VisableButton.Position(3) , VisableButton.Position(4)];
                                CurrentStart = CurrentStart + app.CardInterval;
                            end
                        end
                        app.PlaytheHandOptionOff();
                    else
                        warndlg("请选择合适的牌！");                       
                    end
                end
            end
        end
        
    end
    
    methods (Access = private)
        
        
%         描述：
%         把app.GetChoosedCardList()的返回值 转成 对应的牌的大小值（数字值，而不是字符值）
%         return 列矩阵列表，列表中每个element是一个牌对应的大小值
        function NumCardList = ButtonUpListToCardList(app)
            ButtonUpList = app.GetChoosedCardList();
            StrCardList = [];
            for i = 1:length(ButtonUpList)
                button = eval('app.Button_' + string(ButtonUpList(i)));
                StrCardList = [StrCardList;string(button.Text)];
            end
            NumCardList = strlist2numberlist(StrCardList);
        end
        
%         用来检查一张牌是不是已经出过了
%         如果出过了，就不能再出
%           return Bool
        function result = VisibleCard(app,button)
            result = ((button.Visible=="on") && (button.Enable == "on"));
        end
        
%         return 玩家选择了的牌
%         return的是一个列矩阵，列表中每个element是button的后缀值，这个后缀值是数字
        function result = GetChoosedCardList(app)
            ChoosedCardList = [];
            for i = 1:app.MaxCardNum
                button = eval('app.Button_' + string(i));
                if app.VisibleCard(button)
                    if button.Position(2) ~= app.CardButtonPositionBottom
                        ChoosedCardList = [ChoosedCardList;i];
                    end
                end
            end
            result = ChoosedCardList;
        end
        
%        每个牌点击了会如何反应
%        buttonnum 是App.Button_i 的后缀i的数字形式 也就是说 buttonnum是一个数字 而不是字符
        function ButtonPushed(app,buttonnum)
            button = eval('app.Button_' + string(buttonnum));
            if button.Position(2) == app.CardButtonPositionBottom
                button.Position = button.Position + [0,15,0,0];               
            else
                button.Position = button.Position - [0,15,0,0];
            end            
        end
        
        function ChooseLandlordOptionOff(app)
            app.BeLandlord.Visible = 'off';
            app.BeLandlord.Enable = 'off';
            app.NotBeLandlord.Visible = 'off';
            app.NotBeLandlord.Enable = 'off';
            app.ChooseLandlordEnd= true;
        end
        
        function visiblelist = GetVisibleButtons(app)
            visiblelist = [];
            for i  = 1:20
                button = eval('app.Button_' + string(i));
                if app.VisibleCard(button)
                    visiblelist = [visiblelist;i];
                end
            end
        end
        
        function ButtonUp(app,button)
            button.Position = [button.Position(1),app.initialBottom+15,button.Position(3),button.Position(4)];
        end
        
        function ButtonDown(app,button)
            button.Position = [button.Position(1),app.initialBottom,button.Position(3),button.Position(4)];
        end
        
        function ProperCardsUp(app,cardindex)
            len =  length(cardindex);
%             left = app.CardButtonPositionLeftMost;
            visiblebutton = app.GetVisibleButtons();
            for j = 1:length(visiblebutton)
                button = eval('app.Button_'+string(visiblebutton(j)));
                app.ButtonDown(button);
            end
            if ~isempty(cardindex) 
                for i  = 1:len
                    button = eval('app.Button_'+string(visiblebutton(cardindex(i))));
                    app.ButtonUp(button);
                end
            end
        end
        
        function [singlecard,positions] = AllCardToSingle(app,remaincardnum)
            singlecard = [];
            positions = [];
            for i = 1:length(remaincardnum)
                if ~ismember(remaincardnum(i),singlecard) 
                    singlecard = [singlecard;remaincardnum(i)];
                    positions = [positions;i];
                else
                    continue;
                end
            end
        end
        
        function [doublecard,positions] = AllCardToDouble(app,remaincardnum)
            doublecard = [];
            positions = [];
            for i = 2:length(remaincardnum)
                if remaincardnum(i-1) == remaincardnum(i) && ~ismember(remaincardnum(i),doublecard)
                    doublecard = [doublecard;remaincardnum(i-1);remaincardnum(i)];
                    positions = [positions;i-1;i];
                else
                    continue;
                end
            end
        end        
        
        function result = FindThreeWithOne(app,remaincardnum,keycard)
            
            result = [];
            len = length(remaincardnum);
            for i = 3:len
                if remaincardnum(i) > keycard && remaincardnum(i)==remaincardnum(i-1) && remaincardnum(i-1) == remaincardnum(i-2)  
                    result = [i-2;i-1;i];
                    for j = 1:len
                        if j~=i && j ~=i-1 && j~=i-2 && remaincardnum(j) ~= remaincardnum(i)
                            if j < i-2
                                result = [j;result];
                                break;
                            else
                                result = [result;j];
                                break;
                            end
                        end
                    end
                    break;
                end
            end
            
        end
        
        function result = FindContinueFive(app,remaincardnum,keycard)
            [singlecard,positions] = app.AllCardToSingle(remaincardnum);
            result = [];
            len = length(singlecard);
            for i = 5:len
                if singlecard(i-4) > keycard && singlecard(i) < 15 &&...
                   singlecard(i) == singlecard(i-1)+1 && singlecard(i-1) == singlecard(i-2)+1 &&...
                   singlecard(i-2) == singlecard(i-3)+1 && singlecard(i-3) == singlecard(i-4)+1 
                    result = [positions(i-4);positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end    
            
        end
        
        function result = FindThreeContinuePairs(app,remaincardnum,keycard)
            [doublecard,positions] = app.AllCardToDouble(remaincardnum);
            result = [];
            len = length(doublecard);
            for i = 6:len
                if doublecard(i-5) > keycard &&  doublecard(i) < 15 &&...
                   doublecard(i)==doublecard(i-1) && doublecard(i-1) == doublecard(i-2)+1&& ... 
                   doublecard(i-2) == doublecard(i-3) && doublecard(i-3) == doublecard(i-4)+1&& ...
                   doublecard(i-4) == doublecard(i-5) 
                    result = [positions(i-5);positions(i-4);positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end        
              
        end
        
        function result = FindContinueSeven(app,remaincardnum,keycard)
            [singlecard,positions] = app.AllCardToSingle(remaincardnum);
            result = [];
            len = length(singlecard);
            for i = 7:len
                if singlecard(i-6) > keycard && singlecard(i) < 15 &&...
                   singlecard(i) == singlecard(i-1)+1 && singlecard(i-1) == singlecard(i-2)+1 &&...
                   singlecard(i-2) == singlecard(i-3)+1 && singlecard(i-3) == singlecard(i-4)+1 && ...
                   singlecard(i-4) == singlecard(i-5)+1 && singlecard(i-5) == singlecard(i-6)+1
                    result = [positions(i-6);positions(i-5);positions(i-4);positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end               
        end
        
        function result = FindContinueSix(app,remaincardnum,keycard)
            [singlecard,positions] = app.AllCardToSingle(remaincardnum);
            result = [];
            len = length(singlecard);
            for i = 6:len
                if singlecard(i-5) > keycard && singlecard(i) < 15 &&...
                   singlecard(i) == singlecard(i-1)+1 && singlecard(i-1) == singlecard(i-2)+1 &&...
                   singlecard(i-2) == singlecard(i-3)+1 && singlecard(i-3) == singlecard(i-4)+1 && ...
                   singlecard(i-4) == singlecard(i-5)+1
                    result = [positions(i-5);positions(i-4);positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end            
        end
        
        function result = FindDoubleThreeWithTwo(app,remaincardnum,keycard)
            result = [];
            len = length(remaincardnum);
            for i = 6:len
                if remaincardnum(i-5) > keycard && ...
                   remaincardnum(i)==remaincardnum(i-1) && remaincardnum(i-1) == remaincardnum(i-2) &&...
                   remaincardnum(i-2)==remaincardnum(i-3)+1 && ...
                   remaincardnum(i-3)==remaincardnum(i-4) && remaincardnum(i-4) == remaincardnum(i-5)
                    result = [i-5;i-4;i-3;i-2;i-1;i];
                    for j = 1:len
                        if j~=i && j ~=i-1 && j~=i-2 && j~=i-3 && j ~=i-4 && j~=i-5 && remaincardnum(j) ~=remaincardnum(i) && remaincardnum(j) ~=remaincardnum(i-3)
                           result = [j;result];
                           break;
                        end
                    end
                    for k = 1:len
                        if k~=i && k~=i-1 && k~=i-2 && k~=i-3 && k ~=i-4 && k~=i-5 && k~=j && remaincardnum(k) ~=remaincardnum(i) && remaincardnum(k) ~=remaincardnum(i-3)
    
                            result = [k;result];
                            break;
                        end
                    end
                    result = sort(result);
                    break;
                end
            end         
        end
        
        function result = FindThreeWithPair(app,remaincardnum,keycard)
            result = [];
            len = length(remaincardnum);
            for i = 3:len
                if remaincardnum(i) > keycard && remaincardnum(i)==remaincardnum(i-1) && remaincardnum(i-1) == remaincardnum(i-2)  
                    result = [i-2;i-1;i];
                    for j = 2:len
                        if j ~=i-2 && j~=i-1 && j ~=i && remaincardnum(j)~=remaincardnum(i)
                            if remaincardnum(j) == remaincardnum(j-1)
                                result = [j-1;j;result];
                                result = sort(result);
                                break;
                            end
                        end
                    end
                    break;
                end
            end            
        end
        
        function result = FindBomb(app,remaincardnum,keycard)
            result = [];
            len = length(remaincardnum);
            for i = 4:len
                if remaincardnum(i-3) > keycard && ...
                   remaincardnum(i)==remaincardnum(i-1) && remaincardnum(i-1) == remaincardnum(i-2)...
                   && remaincardnum(i-2) == remaincardnum(i-3)
                    result = [i-3;i-2;i-1;i];
                    break;
                end
            end              
        end
        
        function result = FindContinueEight(app,remaincardnum,keycard)
            [singlecard,positions] = app.AllCardToSingle(remaincardnum);
            result = [];
            len = length(singlecard);
            for i = 8:len
                if singlecard(i-7) > keycard && singlecard(i) < 15 &&...
                   singlecard(i)   == singlecard(i-1)+1 && singlecard(i-1) == singlecard(i-2)+1 &&...
                   singlecard(i-2) == singlecard(i-3)+1 && singlecard(i-3) == singlecard(i-4)+1 && ...
                   singlecard(i-4) == singlecard(i-5)+1 && singlecard(i-5) == singlecard(i-6)+1 && ...
                   singlecard(i-6) == singlecard(i-7)+1
                    result = [positions(i-7);positions(i-6);positions(i-5);positions(i-4);...
                              positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end             
        end
        
        function result = FindDoubleThreeWithDouble(app,remaincardnum,keycard)
            result = [];
            len = length(remaincardnum);
            for i = 6:len
                if remaincardnum(i-5) > keycard && ...
                   remaincardnum(i)==remaincardnum(i-1) && remaincardnum(i-1) == remaincardnum(i-2) &&...
                   remaincardnum(i-2)==remaincardnum(i-3)+1 && ...
                   remaincardnum(i-3)==remaincardnum(i-4) && remaincardnum(i-4) == remaincardnum(i-5)
                    result = [i-5;i-4;i-3;i-2;i-1;i];
                    for j = 2:len
                        if j~=i && j ~=i-1 && j~=i-2 && j~=i-3 && j ~=i-4 && j~=i-5 && remaincardnum(j) ~=remaincardnum(i) && remaincardnum(j) ~=remaincardnum(i-3)
                            if reamincardnum(j) == remaincardunm(j-1)
                                result = [j-1;j;result];
                                break;
                            end
                        end
                    end
                    result = sort(result);
                    break;
                end
            end            
        
        end
        
        function result = FindFourPairs(app,remaincardnum,keycard)
            [doublecard,positions] = app.AllCardToDouble(remaincardnum);
            result = [];
            len = length(doublecard);
            for i = 8:len
                if doublecard(i-7) > keycard &&  doublecard(i) < 15 && ...
                   doublecard(i)   == doublecard(i-1) && doublecard(i-1) == doublecard(i-2)+1&& ... 
                   doublecard(i-2) == doublecard(i-3) && doublecard(i-3) == doublecard(i-4)+1&& ...
                   doublecard(i-4) == doublecard(i-5) && doublecard(i-5) == doublecard(i-6)+1&& ...
                   doublecard(i-6) == doublecard(i-7)
                    result = [positions(i-7);positions(i-6);positions(i-5);positions(i-4);...
                              positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end      
        end
        
        function result = FindContinueNine(app,remaincardnum,keycard)
            [singlecard,positions] = app.AllCardToSingle(remaincardnum);
            result = [];
            len = length(singlecard);
            for i = 9:len
                if singlecard(i-8) > keycard && singlecard(i) < 15 &&...
                   singlecard(i)   == singlecard(i-1)+1 && singlecard(i-1) == singlecard(i-2)+1 &&...
                   singlecard(i-2) == singlecard(i-3)+1 && singlecard(i-3) == singlecard(i-4)+1 && ...
                   singlecard(i-4) == singlecard(i-5)+1 && singlecard(i-5) == singlecard(i-6)+1 && ...
                   singlecard(i-6) == singlecard(i-7)+1 && singlecard(i-7) == singlecard(i-8)+1
                    result = [positions(i-8);positions(i-7);positions(i-6);positions(i-5);positions(i-4);...
                              positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end          
        end
        
        function result = FindFivePairs(app,remaincardnum,keycard)
            [doublecard,positions] = app.AllCardToDouble(remaincardnum);
            result = [];
            len = length(doublecard);
            for i = 10:len
                if doublecard(i-9) > keycard && doublecard(i) < 15 && ...
                   doublecard(i)   == doublecard(i-1) && doublecard(i-1) == doublecard(i-2)+1&& ... 
                   doublecard(i-2) == doublecard(i-3) && doublecard(i-3) == doublecard(i-4)+1&& ...
                   doublecard(i-4) == doublecard(i-5) && doublecard(i-5) == doublecard(i-6)+1&& ...
                   doublecard(i-6) == doublecard(i-7) && doublecard(i-7) == doublecard(i-8)+1&& ...
                   doublecard(i-8) == doublecard(i-9)
                    result = [positions(i-9);positions(i-8);positions(i-7);positions(i-6);positions(i-5);positions(i-4);...
                              positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end             
        end
        
        function result = FindContinueTen(app,remaincardnum,keycard)
            [singlecard,positions] = app.AllCardToSingle(remaincardnum);
            result = [];
            len = length(singlecard);
            for i = 10:len
                if singlecard(i-9) > keycard && singlecard(i) < 15 &&...
                   singlecard(i)   == singlecard(i-1)+1 && singlecard(i-1) == singlecard(i-2)+1 &&...
                   singlecard(i-2) == singlecard(i-3)+1 && singlecard(i-3) == singlecard(i-4)+1 && ...
                   singlecard(i-4) == singlecard(i-5)+1 && singlecard(i-5) == singlecard(i-6)+1 && ...
                   singlecard(i-6) == singlecard(i-7)+1 && singlecard(i-7) == singlecard(i-8)+1 && ...
                   singlecard(i-8) == singlecard(i-9)+1
                    result = [positions(i-9);positions(i-8);positions(i-7);positions(i-6);positions(i-5);...
                              positions(i-4);positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end            
        end
        
        function result = FindContinueEleven(app,remaincardnum,keycard)
            [singlecard,positions] = app.AllCardToSingle(remaincardnum);
            result = [];
            len = length(singlecard);
            for i = 11:len
                if singlecard(i-10) > keycard && singlecard(i) < 15 && ...
                   singlecard(i)   == singlecard(i-1)+1 && singlecard(i-1) == singlecard(i-2)+1 &&...
                   singlecard(i-2) == singlecard(i-3)+1 && singlecard(i-3) == singlecard(i-4)+1 && ...
                   singlecard(i-4) == singlecard(i-5)+1 && singlecard(i-5) == singlecard(i-6)+1 && ...
                   singlecard(i-6) == singlecard(i-7)+1 && singlecard(i-7) == singlecard(i-8)+1 && ...
                   singlecard(i-8) == singlecard(i-9)+1 && singlecard(i-9) == singlecard(i-10)+1
                    result = [positions(i-10);positions(i-9);positions(i-8);positions(i-7);positions(i-6);positions(i-5);...
                              positions(i-4);positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end             
        end
        
        function result = FindSixPairs(app,remaincardnum,keycard)
            [doublecard,positions] = app.AllCardToDouble(remaincardnum);
            result = [];
            len = length(doublecard);
            for i = 11:len
                if doublecard(i-10) > keycard && doublecard(i) < 15 && ...
                   doublecard(i)   == doublecard(i-1) && doublecard(i-1) == doublecard(i-2)+1&& ... 
                   doublecard(i-2) == doublecard(i-3) && doublecard(i-3) == doublecard(i-4)+1&& ...
                   doublecard(i-4) == doublecard(i-5) && doublecard(i-5) == doublecard(i-6)+1&& ...
                   doublecard(i-6) == doublecard(i-7) && doublecard(i-7) == doublecard(i-8)+1&& ...
                   doublecard(i-8) == doublecard(i-9) && doublecard(i-9) == doublecard(i-10)+1&& ...
                   doublecard(i-10) == doublecard(i-11)
                    result = [positions(i-11);positions(i-10);positions(i-9);positions(i-8);positions(i-7);positions(i-6);positions(i-5);positions(i-4);...
                              positions(i-3);positions(i-2);positions(i-1);positions(i)];
                    break;
                end
            end          
        end
        
        function result = FindProperCards(app,kind,keycard)
            remaincardnum = app.GetRemainCardNum();
            result = [];
            remainnumber = length(remaincardnum);
            switch kind
                case 1
                    for i = 1:remainnumber
                        if remaincardnum(i) > keycard
                            result = [i];
                            break;
                        end
                    end
                case 2
                    for i = 2:remainnumber
                        if remaincardnum(i) > keycard && remaincardnum(i) == remaincardnum(i-1)
                            result = [i-1;i];
                            break;
                        end
                    end 
                case 3
                    for i = 3:remainnumber
                        if remaincardnum(i) > keycard && remaincardnum(i)==remaincardnum(i-1) && remaincardnum(i-1) == remaincardnum(i-2)
                            result = [i-2;i-1;i];
                            break;
                        end
                    end
                case 4
                    result = app.FindThreeWithOne(remaincardnum,keycard);
                case 5
                    result = app.FindContinueFive(remaincardnum,keycard);
                case 6
                    result = app.FindThreeContinuePairs(remaincardnum,keycard);
                case 7
                    result = app.FindDoubleThreeWithTwo(remaincardnum,keycard);
                case 8
                    result = app.FindThreeWithPair(remaincardnum,keycard);
                case 9
                    result = app.FindContinueSix(remaincardnum,keycard);
                case 10
                    result = app.FindBomb(remaincardnum,keycard);
                case 11
                    result = app.FindContinueSeven(remaincardnum,keycard);
                case 12
                    result = app.FindContinueEight(remaincardnum,keycard);
                case 13
                    result = app.FindDoubleThreeWithDouble(remaincardnum,keycard);
                case 14
                    result = app.FindFourPairs(remaincardnum,keycard);
                case 15
                    result = app.FindContinueNine(remaincardnum,keycard);
                case 16
                    result = app.FindFivePairs(remaincardnum,keycard);
                case 17 
                    result = app.FindContinueTen(remaincardnum,keycard);
                case 18
                    result = app.FindContinueEleven(remaincardnum,keycard);
                case 19
                    result = app.FindSixPairs(remaincardnum,keycard);
                otherwise
                    result = [];
            end
            if isempty(result) && kind~=10     
                result = app.FindBomb(remaincardnum,0);
            end
        end
    end
           

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.islandlord = false;
            app.ChooseLandlordEnd= false;
            app.CardButtonPositionBottom = app.Button_2.Position(2);
            app.PlaytheHandEnd = false;
            app.ChoosedCardNum = [];
            app.CardButtonPositionLeftMost = app.Button_1.Position(1);
            app.MaxCardNum = 20;
            app.CardInterval = 30;
            app.PlaytheHandEnd = false;
            app.initialBottom = app.Button_1.Position(2);
            app.tuoguanstatue = false;
        end

        % Button pushed function: BeLandlord
        function BeLandlordButtonPushed(app, event)
            app.islandlord = true;
            app.ChooseLandlordOptionOff();
        end

        % Button pushed function: NotBeLandlord
        function NotBeLandlordButtonPushed(app, event)
            app.islandlord = false;
            app.ChooseLandlordOptionOff();
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.ButtonPushed(1);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ButtonPushed(2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ButtonPushed(3);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.ButtonPushed(4);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.ButtonPushed(5);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.ButtonPushed(6);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.ButtonPushed(7);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.ButtonPushed(8);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.ButtonPushed(9);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.ButtonPushed(10);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.ButtonPushed(11);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.ButtonPushed(12);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.ButtonPushed(13);
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.ButtonPushed(14);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.ButtonPushed(15);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.ButtonPushed(16);
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.ButtonPushed(17);
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.ButtonPushed(18);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.ButtonPushed(19);
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.ButtonPushed(20);
        end

        % Button pushed function: PlaytheHand
        function PlaytheHandButtonPushed(app, event)
            app.PlaytheHandFunc();
            app.ChoosePlayTheHand = true;
            if app.PlaytheHandEnd
                app.Game.PlaytheHand_post(app.Game.Process,app.Game.currentplayer,app.Game.currentplayerui);
            end
        end

        % Button pushed function: NotPlaytheHand
        function NotPlaytheHandButtonPushed(app, event)
           app.PlaytheHandOptionOff();
           app.ChoosePlayTheHand = false;
           app.Game.PlaytheHand_post(app.Game.Process,app.Game.currentplayer,app.Game.currentplayerui);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: HintButton
        function HintButtonPushed(app, event)
            [kind,keycard] = CardListKind(app.LastCardList);
            ProperCardIndex = app.FindProperCards(kind,keycard);
            app.ProperCardsUp(ProperCardIndex);
        end

        % Button pushed function: tuoguanButton
        function tuoguanButtonPushed(app, event)
            app.tuoguanstatue = true;
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            app.tuoguanstatue = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InfoBox = uipanel(app.UIFigure);
            app.InfoBox.Title = '玩家 信息';
            app.Message = uilabel(app.InfoBox);
            app.Message.Text = '';
            app.BeLandlord = uibutton(app.UIFigure, 'push');
            app.BeLandlord.ButtonPushedFcn = createCallbackFcn(app, @BeLandlordButtonPushed, true);
            app.BeLandlord.Enable = 'off';
            app.BeLandlord.Visible = 'off';
            app.BeLandlord.Text = '抢地主';
            app.NotBeLandlord = uibutton(app.UIFigure, 'push');
            app.NotBeLandlord.ButtonPushedFcn = createCallbackFcn(app, @NotBeLandlordButtonPushed, true);
            app.NotBeLandlord.Enable = 'off';
            app.NotBeLandlord.Visible = 'off';
            app.NotBeLandlord.Text = '不抢';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '♠';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '♥';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '♦';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '♣';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '♠';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '♥';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '♦';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '♣';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '♠';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '♥';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '♦';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '♣';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '♠';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '♥';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '♦';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '♣';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '♠';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '♥';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '♦';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '♣';
            app.player2Label = uilabel(app.UIFigure);
            app.player2Label.Text = 'player2剩余牌数';
            app.playerRightTextArea = uitextarea(app.UIFigure);
            app.playerRightTextArea.Editable = 'off';
            app.playerRightTextArea.Value = {'17'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'nongming1.jpg';
            app.PlaytheHand = uibutton(app.UIFigure, 'push');
            app.PlaytheHand.ButtonPushedFcn = createCallbackFcn(app, @PlaytheHandButtonPushed, true);
            app.PlaytheHand.Enable = 'off';
            app.PlaytheHand.Visible = 'off';
            app.PlaytheHand.Text = '出牌';
            app.player3Label = uilabel(app.UIFigure);
            app.player3Label.Text = 'player3剩余牌数';
            app.playerLeftTextArea = uitextarea(app.UIFigure);
            app.playerLeftTextArea.Editable = 'off';
            app.playerLeftTextArea.Value = {'17'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '地主是玩家';
            app.landlord = uitextarea(app.UIFigure);
            app.landlord.Editable = 'off';
            app.NotPlaytheHand = uibutton(app.UIFigure, 'push');
            app.NotPlaytheHand.ButtonPushedFcn = createCallbackFcn(app, @NotPlaytheHandButtonPushed, true);
            app.NotPlaytheHand.Enable = 'off';
            app.NotPlaytheHand.Visible = 'off';
            app.NotPlaytheHand.Text = '不出';
            app.HintButton = uibutton(app.UIFigure, 'push');
            app.HintButton.ButtonPushedFcn = createCallbackFcn(app, @HintButtonPushed, true);
            app.HintButton.Enable = 'off';
            app.HintButton.Visible = 'off';
            app.HintButton.Text = '提示';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'nongming1.jpg';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = 'nongming1.jpg';
            app.tuoguanButton = uibutton(app.UIFigure, 'push');
            app.tuoguanButton.ButtonPushedFcn = createCallbackFcn(app, @tuoguanButtonPushed, true);
            app.tuoguanButton.Text = '托管';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '取消托管';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = player1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end